classdef diffuusio_demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        showresultsCheckBox           matlab.ui.control.CheckBox
        SavehistogramsButton          matlab.ui.control.Button
        SimstepSpinner                matlab.ui.control.Spinner
        SimstepSpinnerLabel           matlab.ui.control.Label
        ParticlesSpinner              matlab.ui.control.Spinner
        ParticlesSpinnerLabel         matlab.ui.control.Label
        SpeedSlider                   matlab.ui.control.Slider
        SpeedSliderLabel              matlab.ui.control.Label
        RestartButton                 matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        DiffusiontypeButtonGroup      matlab.ui.container.ButtonGroup
        RestrictedanisotropicButton   matlab.ui.control.RadioButton
        Restrictedanisotropic2Button  matlab.ui.control.RadioButton
        Restrictedanisotropic1Button  matlab.ui.control.RadioButton
        RestrictedisotropicButton     matlab.ui.control.RadioButton
        FreeButton                    matlab.ui.control.RadioButton
        RightPanel                    matlab.ui.container.Panel
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (SetObservable)
        appRunning = false;
        appInitialized = false;
%         numberParticles = app.NumberSpinnder.Value;
%         speed = app.SpeedSlider.Value;
%         restrictionType = app.DiffusiontypeButtonGroup.SelectedObject;
    end
    
    properties (Access = public)
       particles = struct();
       mask;
       dots;
       x0;
       y0;
       x0s; 
       y0s;
       W = 640;
       H = 480;
       xlabel="Distance from origin";
       niter = 0;
       edges;
       X;
       Y;
    end
    
    methods (Access = private)
        
        function simulate(app)
            if ~app.appInitialized
                app.initialize();
            end
            N = length(app.particles);
            xdata = zeros(N,1);
            ydata = zeros(N,1);
            app.niter = 0;
            while app.appRunning
                for i = 1:N
                    dx = 2.0*rand - 1.0;
                    dy = 2.0*rand - 1.0;
                    dx = dx * app.SpeedSlider.Value;
                    dy = dy * app.SpeedSlider.Value;
                    
                    x = app.particles(i).x;
                    y = app.particles(i).y;
                    newx = x + dx;
                    newy = y + dy;
                    
                    % Check out of borders
                    if newx < 1
                        newx = newx + app.W - 1;
                    elseif newx > app.W
                        newx = newx - app.W + 1;
                    end
                    if newy < 1
                        newy = newy + app.H - 1;
                    elseif newy > app.H
                        newy = newy - app.H + 1;
                    end
                    
                    % Check collisions
                    while true
                        mask_val_0 = app.mask.mask(round(y), round(x));
                        mask_val_1 = app.mask.mask(round(newy), round(newx));
                        if mask_val_0 == mask_val_1
                            break;
                        end
                        dx = 2.0*rand - 1.0;
                        dy = 2.0*rand - 1.0;
                        dx = dx * app.SpeedSlider.Value;
                        dy = dy * app.SpeedSlider.Value;
                        newx = x + dx;
                        newy = y + dy;
                        
                        % Check out of borders
                        if newx < 1
                            newx = newx + app.W - 1;
                        elseif newx > app.W
                            newx = newx - app.W + 1;
                        end
                        if newy < 1
                            newy = newy + app.H - 1;
                        elseif newy > app.H
                            newy = newy - app.H + 1;
                        end
                    end
                   
%                     % Check collisions
%                     collidex = app.mask.mask(round(y), round(newx));
%                     collidey = app.mask.mask(round(newy), round(x));
%                     if collidex
%                         newx = x - dx;
%                     end
%                     if collidey
%                         newy = y - dy;
%                     end
%                     % Check out of borders
%                     if newx < 1
%                         newx = newx + app.W - 1;
%                     elseif newx > app.W
%                         newx = newx - app.W + 1;
%                     end
%                     if newy < 1
%                         newy = newy + app.H - 1;
%                     elseif newy > app.H
%                         newy = newy - app.H + 1;
%                     end
                    app.particles(i).x = newx;
                    app.particles(i).y = newy;
                    xdata(i) = newx;
                    ydata(i) = newy;
                end
                
                % Histograms
                xdist = xdata - app.x0s;
                ydist = ydata - app.y0s;
                % Check if particle has gone over edges
                % We are not interested in the abs(min) values, but their
                % indices on each row.
                [~, xinds] = min(abs(xdist), [], 2);
                [~, yinds] = min(abs(ydist), [], 2);
                % Since xdist is 2d array, simple row-wise index is not
                % enough to locate the original min values but sub2ind
                % needs to be called with a 2d array:
                xinds = sub2ind(size(xdist), (1:N)', xinds);
                yinds = sub2ind(size(ydist), (1:N)', yinds);
                xdist = xdist(xinds);
                ydist = ydist(yinds);
                
                [X,edges] = histcounts(xdist, 101, 'Normalization','pdf');
                [Y,edges] = histcounts(ydist, edges, 'Normalization','pdf');
                edges = edges(2:end) - (edges(2)-edges(1))/2;
                app.X = X;
                app.Y = Y;
                app.edges = edges;
                
                % Update axes
                app.dots.XData = xdata;
                app.dots.YData = ydata;
                
                if app.showresultsCheckBox.Value
    %                 histogram(app.UIAxes, xdist);
                    plot(app.UIAxes, app.edges, smoothdata(app.X), 'linewidth', 3);
                    hold(app.UIAxes, 'on');
    %                 histogram(app.UIAxes, ydist);
                    plot(app.UIAxes, app.edges, smoothdata(app.Y), 'linewidth', 3);
                    hold(app.UIAxes, 'off');
                    app.UIAxes.XLim = [-150, 150];
    %                 app.UIAxes.YLim = ylim;
                    [hleg,att] = legend(app.UIAxes, 'X', 'Y');
                    app.UIAxes.XLabel.String = strcat(app.xlabel, " t = ", num2str(app.niter), " s");
                    
                    drawnow;
                    pause(0.001);
                else
                    disp(app.niter);
                end
                
                app.niter = app.niter + 1;
                if app.niter >= app.SimstepSpinner.Value
                    app.appRunning = false;
                    app.StartButton.Text = "Start";
                end
            end
        end
        
        function initialize(app)
            % Set GUI elements
            xlim = [1,app.W];
            ylim = [1,app.H];
            app.UIAxes2.XLim = xlim;
            app.UIAxes2.YLim = ylim;
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.Box = true;
            app.UIAxes.Box = true;
            app.UIAxes.YTick = [];
            % Set restriction
            type = app.DiffusiontypeButtonGroup.SelectedObject.Text;
            app.mask = restriction(type, app.W, app.H);
            imagesc(app.mask.mask, 'Parent', app.UIAxes2);
            hold(app.UIAxes2,'on')
            % Initialize particles
            app.dots = scatter(-10,-10, 'filled', 'Parent', ...
                app.UIAxes2,'MarkerEdgeColor',[0.5, 0, 0.5],...
              'MarkerFaceColor',[0.7, 0, 0.7], 'LineWidth', 1.5);
            hold(app.UIAxes2,'off')
            set(app.UIAxes2,'DataAspectRatio',[1 1 1])
            N = app.ParticlesSpinner.Value;
            app.x0 = zeros(N,1);
            app.y0 = zeros(N,1);
            for i = 1:N
                while true
                    x = round((xlim(2)-xlim(1))*rand + xlim(1));
                    y = round((ylim(2)-ylim(1))*rand + ylim(1));
                    if app.mask.mask(y,x) > 0
                        app.particles(i).x = x;
                        app.particles(i).y = y;
                        app.particles(i).origx = x;
                        app.particles(i).origy = y;
                        break;
                    end
                end
                if i == 1
                    app.dots.XData = app.particles(i).x;
                    app.dots.YData = app.particles(i).y;
                else
                    app.dots.XData = [app.dots.XData, app.particles(i).x];
                    app.dots.YData = [app.dots.YData, app.particles(i).y];    
                end
                app.x0(i) = x;
                app.y0(i) = y;
            end
            % Mirror initial (x,y) points over edges to enable tracking
            % particles that cross the edges.
            app.x0s = [app.x0-app.W, app.x0, app.x0+app.W];
            app.y0s = [app.y0-app.H, app.y0, app.y0+app.H];
            app.appInitialized = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if app.appRunning
                app.appRunning = false;
                app.StartButton.Text = "Start";
            else
                app.appRunning = true;
                app.StartButton.Text = "Pause";
                app.simulate();
            end
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.initialize();
        end

        % Button pushed function: SavehistogramsButton
        function SavehistogramsButtonPushed(app, event)

            fname = strcat(app.DiffusiontypeButtonGroup.SelectedObject.Text, "_niter_", num2str(app.niter), "_speed_", num2str(app.SpeedSlider.Value));
 
%                 histogram(app.UIAxes, xdist);
            plot(app.UIAxes, app.edges, smoothdata(app.X), 'linewidth', 3);
            hold(app.UIAxes, 'on');
%                 histogram(app.UIAxes, ydist);
            plot(app.UIAxes, app.edges, smoothdata(app.Y), 'linewidth', 3);
            hold(app.UIAxes, 'off');
            app.UIAxes.XLim = [-150, 150];
%                 app.UIAxes.YLim = ylim;
            [hleg,att] = legend(app.UIAxes, 'X', 'Y');
            app.UIAxes.XLabel.String = strcat(app.xlabel, " t = ", num2str(app.niter), " s");
            
            drawnow;
            pause(0.001);
            
            % Get data
%             x1 = app.UIAxes.Children(1).XData;
%             y1 = app.UIAxes.Children(1).YData;
%             x2 = app.UIAxes.Children(2).XData;
%             y2 = app.UIAxes.Children(2).YData;
            y1 = app.X;
            y2 = app.Y;
            x1 = app.edges;
            x2 = app.edges;
            % Fit normal distributions
            fitfun= fittype( @(scale,mu,sd,x) scale/sqrt(2*pi*sd^2)*exp(-(x-mu).^2/(2*sd^2)) );
            c0 = [1 0 10];
            
            [fitted_curve1,gof1] = fit(x1',y1',fitfun,'StartPoint',c0);
            coeffvals1 = coeffvalues(fitted_curve1);
            mu1 = coeffvals1(2);
            std1 = coeffvals1(3);
            
            [fitted_curve2,gof2] = fit(x2',y2',fitfun,'StartPoint',c0);
            coeffvals2 = coeffvalues(fitted_curve2);
            mu2 = coeffvals2(2);
            std2 = coeffvals2(3);
          
            
            % Make new figure and copy UIAxes (histograms) into that for
            % saving with specific resolution etc.
            h = figure;
            h.Visible = 'off';
            x_t = linspace(-150,150,1000);
            
            corange = [245,130,48]/255;
            corange2 = [245/2,130/2,48/2]/255;
            cteal = [0,128,128]/255;
            cteal2 = [0,255,255]/255;
            plot(x_t, fitted_curve1(x_t), 'linewidth', 3, "color", corange)
            hold on;
            plot(x_t, fitted_curve2(x_t), 'linewidth', 3, "color", cteal)
            scatter(x1,y1,20, 'o', 'markerfacecolor', corange, "MarkerEdgeColor",corange2);
            scatter(x2,y2,20, 'o', 'markerfacecolor', cteal, "MarkerEdgeColor",cteal2);
            
            lgd = legend("Normal distribution", "Normal distribution", ...
                app.UIAxes.Legend.String{1}, app.UIAxes.Legend.String{2});
            lgd.Box = app.UIAxes.Legend.Box;
            lgd.Location = app.UIAxes.Legend.Location;
            h.CurrentAxes.YLabel.String = app.UIAxes.YLabel.String;
            h.CurrentAxes.YLabel.FontSize = app.UIAxes.YLabel.FontSize;
            h.CurrentAxes.XLabel.String = app.UIAxes.XLabel.String;
            h.CurrentAxes.XLabel.FontSize = app.UIAxes.XLabel.FontSize;
            h.CurrentAxes.Title.String = app.UIAxes.Title.String;
            h.CurrentAxes.Title.FontSize = app.UIAxes.Title.FontSize;
            h.CurrentAxes.XLim = [-150, 150];
            h.CurrentAxes.YLim = [0 max([y1, y2])+0.001];
           
            str1 = {['\mu_X=',num2str(mu1)],...
                ['\sigma_X = ',num2str(std1)],...
                ['R^2_X = ',num2str(gof1.rsquare)]};
            box1 = annotation('textbox','FitBoxToText','on');
            box1.Parent = gca;
            box1.Position = [-150, h.CurrentAxes.YLim(2)/1.2, ...
                               100, 0.01];
            box1.VerticalAlignment = 'top';
            box1.EdgeColor = 'none';
            box1.Color = corange;
            box1.String = str1;
            box1.FontSize = 16;
            
            str2 = {['\mu_Y=',num2str(mu2)],...
                ['\sigma_Y = ',num2str(std2)],...
                ['R^2_Y = ',num2str(gof2.rsquare)]};
            box1 = annotation('textbox','FitBoxToText','on');
            box1.Parent = gca;
            box1.Position = [80, h.CurrentAxes.YLim(2)/1.2, ...
                               100, 0.01];
            box1.VerticalAlignment = 'top';
            box1.EdgeColor = 'none';
            box1.Color = cteal;
            box1.String = str2;
            box1.FontSize = 16;
            
            % Save figure
            print(h,fname,'-dpng','-r300')
            savefig(h,fname)
            delete(h)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DiffusiontypeButtonGroup = uibuttongroup(app.LeftPanel);
            app.DiffusiontypeButtonGroup.Title = 'Diffusion type';
            app.DiffusiontypeButtonGroup.Tag = 'type';
            app.FreeButton = uiradiobutton(app.DiffusiontypeButtonGroup);
            app.FreeButton.Text = 'Free';
            app.FreeButton.Value = true;
            app.RestrictedisotropicButton = uiradiobutton(app.DiffusiontypeButtonGroup);
            app.RestrictedisotropicButton.Text = 'Restricted - isotropic';
            app.Restrictedanisotropic1Button = uiradiobutton(app.DiffusiontypeButtonGroup);
            app.Restrictedanisotropic1Button.Text = 'Restricted - anisotropic1';
            app.Restrictedanisotropic2Button = uiradiobutton(app.DiffusiontypeButtonGroup);
            app.Restrictedanisotropic2Button.Text = 'Restricted - anisotropic2';
            app.RestrictedanisotropicButton = uiradiobutton(app.DiffusiontypeButtonGroup);
            app.RestrictedanisotropicButton.Text = 'Restricted - an+isotropic';
            app.StartButton = uibutton(app.LeftPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Tag = 'startpause';
            app.StartButton.Text = 'Start';
            app.RestartButton = uibutton(app.LeftPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Tag = 'restart';
            app.RestartButton.Text = 'Restart';
            app.SpeedSliderLabel = uilabel(app.LeftPanel);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.LeftPanel);
            app.SpeedSlider.Limits = [0 20];
            app.SpeedSlider.Tag = 'speed';
            app.SpeedSlider.Value = 5;
            app.ParticlesSpinnerLabel = uilabel(app.LeftPanel);
            app.ParticlesSpinnerLabel.Tag = 'numparticles';
            app.ParticlesSpinnerLabel.Text = 'Particles #';
            app.ParticlesSpinner = uispinner(app.LeftPanel);
            app.ParticlesSpinner.Limits = [0 10000];
            app.ParticlesSpinner.Value = 100;
            app.SimstepSpinnerLabel = uilabel(app.LeftPanel);
            app.SimstepSpinnerLabel.Text = 'Sim. step # ';
            app.SimstepSpinner = uispinner(app.LeftPanel);
            app.SimstepSpinner.Limits = [0 10000];
            app.SimstepSpinner.Value = 100;
            app.SavehistogramsButton = uibutton(app.LeftPanel, 'push');
            app.SavehistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @SavehistogramsButtonPushed, true);
            app.SavehistogramsButton.Text = 'Save histograms';
            app.showresultsCheckBox = uicheckbox(app.LeftPanel);
            app.showresultsCheckBox.Text = 'show results';
            app.showresultsCheckBox.Value = true;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'Distance from origin')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Tag = 'histogram';
            app.UIAxes2 = uiaxes(app.RightPanel);
            app.UIAxes2.Tag = 'space';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = diffuusio_demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end